classdef perovXimager < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        GridLayout2                     matlab.ui.container.GridLayout
        xbandgapLabel                   matlab.ui.control.Label
        pitchEditField                  matlab.ui.control.NumericEditField
        pitchumEditFieldLabel_2         matlab.ui.control.Label
        umesEditField                   matlab.ui.control.NumericEditField
        MaterialThicknessLabel_14       matlab.ui.control.Label
        massAttCoefByEnergyEditField    matlab.ui.control.NumericEditField
        MaterialThicknessLabel_13       matlab.ui.control.Label
        E_pairEditField                 matlab.ui.control.NumericEditField
        MaterialThicknessLabel_12       matlab.ui.control.Label
        E_bandGapEditField              matlab.ui.control.NumericEditField
        MaterialThicknessLabel_11       matlab.ui.control.Label
        EmesEditField                   matlab.ui.control.NumericEditField
        MaterialThicknessLabel_10       matlab.ui.control.Label
        massAttCoefEditField            matlab.ui.control.NumericEditField
        MaterialThicknessLabel_9        matlab.ui.control.Label
        thicknessEditField              matlab.ui.control.NumericEditField
        MaterialThicknessLabel_8        matlab.ui.control.Label
        DetectorAreaEditField           matlab.ui.control.NumericEditField
        Detectorareamm2Label_2          matlab.ui.control.Label
        loadExampleButton               matlab.ui.control.Button
        unitsEnergyDropDown             matlab.ui.control.DropDown
        unitsLinAttCoefDropDown         matlab.ui.control.DropDown
        unitsDoseNoiseDropDown          matlab.ui.control.DropDown
        unitsDoseSignalDropDown         matlab.ui.control.DropDown
        unitsNoiseDropDown              matlab.ui.control.DropDown
        unitsSignalDropDown             matlab.ui.control.DropDown
        CalculateButton                 matlab.ui.control.Button
        fileAddressDetNoise2D           matlab.ui.control.TextArea
        fileAddressPixelCoord           matlab.ui.control.TextArea
        fileAddressESF                  matlab.ui.control.TextArea
        fileAddressEnergy               matlab.ui.control.TextArea
        fileAddressLinCoef              matlab.ui.control.TextArea
        fileAddressDoseN                matlab.ui.control.TextArea
        fileAddressNoise                matlab.ui.control.TextArea
        fileAddressDoseS                matlab.ui.control.TextArea
        fileAddressSignal               matlab.ui.control.TextArea
        DetectorNoise2DButton           matlab.ui.control.Button
        PixelsnumbersButton             matlab.ui.control.Button
        ESFButton                       matlab.ui.control.Button
        EnergyButton                    matlab.ui.control.Button
        LinattencoefButton              matlab.ui.control.Button
        FileinputLabel                  matlab.ui.control.Label
        DosenoiseButton                 matlab.ui.control.Button
        NoiseButton                     matlab.ui.control.Button
        DosesignalButton                matlab.ui.control.Button
        SignalButton                    matlab.ui.control.Button
        RightPanel                      matlab.ui.container.Panel
        TabGroup                        matlab.ui.container.TabGroup
        SinglepixelcharacterizationTab  matlab.ui.container.Tab
        unitsDoseDQEDropDown            matlab.ui.control.DropDown
        SetDoseRangeLabel               matlab.ui.control.Label
        stepDoseEditField               matlab.ui.control.NumericEditField
        stepEditFieldLabel_2            matlab.ui.control.Label
        maxDoseEditField                matlab.ui.control.NumericEditField
        maxEditFieldLabel_2             matlab.ui.control.Label
        minDoseEditField                matlab.ui.control.NumericEditField
        minEditFieldLabel_2             matlab.ui.control.Label
        SpecifythedoseinphotonsLabel    matlab.ui.control.Label
        DQEcurve3EditField              matlab.ui.control.NumericEditField
        curve3EditFieldLabel_2          matlab.ui.control.Label
        DQEcurve2EditField              matlab.ui.control.NumericEditField
        curve2EditFieldLabel_2          matlab.ui.control.Label
        DQEcurve1EditField              matlab.ui.control.NumericEditField
        curve1EditFieldLabel_2          matlab.ui.control.Label
        NEDphotonTextArea               matlab.ui.control.TextArea
        DetectionefficiencyLabel_8      matlab.ui.control.Label
        NEDdoseTextArea                 matlab.ui.control.TextArea
        DetectionefficiencyLabel_7      matlab.ui.control.Label
        DE_2TextArea                    matlab.ui.control.TextArea
        DetectionefficiencyLabel_6      matlab.ui.control.Label
        DETextArea                      matlab.ui.control.TextArea
        DetectionefficiencyLabel_5      matlab.ui.control.Label
        TheoreticalSensitivityTextArea  matlab.ui.control.TextArea
        TheoreticalsensitivityTextAreaLabel_2  matlab.ui.control.Label
        DQE_E_plot                      matlab.ui.control.UIAxes
        NED_E_plot                      matlab.ui.control.UIAxes
        DE_E_plot                       matlab.ui.control.UIAxes
        AE_plot                         matlab.ui.control.UIAxes
        DQE_dose_plot                   matlab.ui.control.UIAxes
        NoiseToDose                     matlab.ui.control.UIAxes
        SignalToDose                    matlab.ui.control.UIAxes
        ArraydetectorcharacterizationTab  matlab.ui.container.Tab
        MTFlimitEditField               matlab.ui.control.NumericEditField
        limitlpmmLabel_2                matlab.ui.control.Label
        NNPS_plot                       matlab.ui.control.UIAxes
        DQE_f_plot                      matlab.ui.control.UIAxes
        NPS_plot                        matlab.ui.control.UIAxes
        Noise2D_plot                    matlab.ui.control.UIAxes
        MTF_plot                        matlab.ui.control.UIAxes
        LSF_plot                        matlab.ui.control.UIAxes
        ESF_plot                        matlab.ui.control.UIAxes
        VitaliiBartoshKovalenkoGroupETHZurichLabel  matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        d;
        detectorArea;
        e_charge = 1.602*10^-19;
        Signal;
        Signal_multiplier;
        Signal_loaded;
        DoseSignal;
        DoseSignal_loaded;
        DoseSignal_multiplier;
        Noise;
        Noise_multiplier;
        Noise_loaded;
        DoseNoise;
        DoseNoise_loaded;
        DoseNoise_multiplier;
        u;
        u_loaded;
        E_u;
        E_u_loaded;
        ESF;
        distance;
        coordinates;
        NoiseMatrix;
        eBandGap;
        ePair; %electron-hole pair creation energy
        u_p;
        senTheory;
        slopeS;
        slopeS_theory;
        slopeN;
        DE;
        DE_2;
        u_p_E_av; % 4 kev*cm^2*g^-1 // product of x-ray energy and mass attenuation coefficient, averaged 
        OnePhotonDose;
        NED;
        NED_photon;
        dose_dqe;
        DQE_array;
        AE;
        E;
        E_mes;
        u_mes;
        AE_mes;
        DE_E;
        NED_E;
        LSF;
        
        is_u_loaded = 0;
        is_Eu_loaded = 0;
        commonPath = 'C:\Users\Public\Documents\';
    end
    
    methods (Access = private)
        
        function res = photonToDose(app,ph,uE_coef,detArea)
            res = uE_coef*1.602*10^-17*ph/detArea; %in Gy
        end
        
        function res = u_interpolate(app,query,x,y)
            res = spline(x,y,query);
        end
        
        function res = DQE_E(app,DE_E,NED_E,dose)
             res = DE_E./(1+NED_E/dose);
        end
        
        function out = DQE(app,dose,de,ned)
            out = de/(1+ned/dose);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.Name = 'perovXimager';
        end

        % Button pushed function: SignalButton
        function SignalButtonPushed(app, event)
            %%%%
            %[file,path] = uigetfile({'*.txt';'*.dat';'*.*'},app.commonPath);
            [file,path] = uigetfile(app.commonPath +"*.txt;*.dat");
            fileID = fopen(fullfile(path,file), 'r');
            app.Signal_loaded = fscanf(fileID,"%f");
            fclose(fileID);
            app.fileAddressSignal.Value = file;
            app.commonPath = path;
            

        end

        % Button pushed function: DosesignalButton
        function DosesignalButtonPushed(app, event)
            %[file,path] = uigetfile({'*.txt';'*.dat';'*.*'},app.commonPath);
            [file,path] = uigetfile(app.commonPath +"*.txt;*.dat");
            fileID = fopen(fullfile(path,file), 'r');
            app.DoseSignal_loaded = fscanf(fileID,"%f"); %in ?Gy now
            fclose(fileID);
            app.fileAddressDoseS.Value = file;
            app.commonPath = path;
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            
            %signal/dose
            switch(app.unitsSignalDropDown.Value)
                case 'C'
                    app.Signal_multiplier = 1;
                case 'mC'
                    app.Signal_multiplier = 10^-3;
                case 'uC'
                    app.Signal_multiplier = 10^-6;
            end
            app.Signal = app.Signal_multiplier*app.Signal_loaded;
            switch (app.unitsDoseSignalDropDown.Value)
                case 'Gy'
                    app.DoseSignal_multiplier = 1;
                case 'mGy'
                    app.DoseSignal_multiplier = 10^-3;
                case 'uGy'
                    app.DoseSignal_multiplier = 10^-6;
            end
            
            app.DoseSignal = app.DoseSignal_multiplier*app.DoseSignal_loaded;
            cla(app.SignalToDose);
            %scatter(app.SignalToDose,app.DoseSignal,app.Signal, 25, "blue","filled");
            scatter(app.SignalToDose,app.DoseSignal/app.DoseSignal_multiplier,app.Signal/app.Signal_multiplier, 25, 'MarkerFaceColor',[0.48 0.82 0.76]);
             S_D_fit_pol = polyfit(app.DoseSignal,app.Signal,1);
             SD_fit = polyval(S_D_fit_pol, app.DoseSignal);
             hold (app.SignalToDose,'on');
             xlabel(app.SignalToDose,"dose, " +app.unitsDoseSignalDropDown.Value);
             ylabel(app.SignalToDose,"Signal, " +app.unitsSignalDropDown.Value);
             plot(app.SignalToDose,app.DoseSignal/app.DoseSignal_multiplier,SD_fit/app.Signal_multiplier,'Color',[0.48 0.82 0.76],'LineWidth',1.5) %signal/dose dependence
             hold (app.SignalToDose,'off');

             % numerical values
             app.d = app.thicknessEditField.Value*10^-3; % in mm
             app.detectorArea = app.DetectorAreaEditField.Value*10^-6; % im m^2
             app.eBandGap = app.E_bandGapEditField.Value*app.e_charge;
            app.ePair = app.E_pairEditField.Value *app.eBandGap;
            app.u_p = 10^-1*app.massAttCoefEditField.Value;
            app.u_p_E_av = app.massAttCoefByEnergyEditField.Value;
            app.senTheory = app.e_charge/app.ePair/app.u_p; % C*m^-2*Gy^-1
            app.slopeS = S_D_fit_pol(1);
            app.slopeS_theory = app.senTheory*app.detectorArea;
            app.DE = app.slopeS / app.slopeS_theory;
            app.TheoreticalSensitivityTextArea.Value = app.senTheory + " C m^-2 Gy^-1";
            app.DETextArea.Value = num2str(app.DE);

            %sq Noise/dose
            switch(app.unitsNoiseDropDown.Value)
                case 'C'
                    app.Noise_multiplier = 1;
                case 'mC'
                    app.Noise_multiplier = 10^-3;
                case 'uC'
                    app.Noise_multiplier = 10^-6;
            end
            app.Noise = app.Noise_multiplier*app.Noise_loaded;
            switch (app.unitsDoseNoiseDropDown.Value)
                case 'Gy'
                    app.DoseNoise_multiplier = 1;
                case 'mGy'
                    app.DoseNoise_multiplier = 10^-3;
                case 'uGy'
                    app.DoseNoise_multiplier = 10^-6;
            end
            app.DoseNoise = app.DoseNoise_multiplier*app.DoseNoise_loaded;
            N_D_fit_pol = polyfit(app.DoseNoise,app.Noise.^2,1);
            ND_fit = polyval(N_D_fit_pol, app.DoseNoise);
            app.slopeN = N_D_fit_pol(1);
            cla(app.NoiseToDose);
            legend(app.NoiseToDose,'Location','northwest');
            scatter(app.NoiseToDose,app.DoseNoise/app.DoseNoise_multiplier,(app.Noise/app.Noise_multiplier).^2, 25, 'MarkerFaceColor',[0.48 0.82 0.76],'DisplayName',"data points"); %noise/dose dependence
            hold(app.NoiseToDose, 'on');
             xlabel(app.NoiseToDose,"dose, " +app.unitsDoseNoiseDropDown.Value);
             ylabel(app.NoiseToDose,"Noise^2, " +app.unitsNoiseDropDown.Value+ "^2");
            plot(app.NoiseToDose,app.DoseNoise/app.DoseNoise_multiplier,ND_fit/(app.Noise_multiplier^2),'Color',[0.48 0.82 0.76],'LineWidth',1.5,'DisplayName',"original fit"); %noise/dose dependence
             %%????????????????????????
            %new_noise_intercept = app.fitInterceptCorrectionEditField.Value;
            new_noise_intercept = app.Noise(1)^2;
            y2 = N_D_fit_pol(1)*app.DoseNoise + new_noise_intercept;
            
            plot(app.NoiseToDose, app.DoseNoise/app.DoseNoise_multiplier, y2/(app.Noise_multiplier^2),'Color',[0.7 0.7 0.7],'LineWidth',1.5,'DisplayName',"corrected fit");
            hold(app.NoiseToDose, 'off');
            %
            app.OnePhotonDose = photonToDose(app,1,app.u_p_E_av,app.detectorArea); %in Gy
            app.DE_2 = app.slopeS^2*app.OnePhotonDose/app.slopeN;
            app.NED = new_noise_intercept/app.slopeN; %in Gy
            app.NED_photon = app.NED/app.OnePhotonDose;
            app.DE_2TextArea.Value = num2str(app.DE_2);
            app.NEDdoseTextArea.Value = num2str(app.NED/app.DoseNoise_multiplier) +" "+app.unitsDoseNoiseDropDown.Value;
            app.NEDphotonTextArea.Value = num2str(app.NED_photon);
            doseCoef = 0;
            %DQE
            switch(app.unitsDoseDQEDropDown.Value)
                case 'Gy'
                    doseCoef = 1;
                case 'mGy'
                    doseCoef = 10^-3;
                case 'uGy'
                    doseCoef = 10^-6;
            end
            minDose = doseCoef*app.minDoseEditField.Value;
            maxDose = doseCoef*app.maxDoseEditField.Value;
            stepDose = doseCoef*app.stepDoseEditField.Value;
            app.dose_dqe = minDose:minDose+stepDose:maxDose;
            %app.DQE_array = app.DE_2/(1+app.NED/app.dose_dqe);
            app.DQE_array = zeros(size(app.dose_dqe));
            for i=1:size(app.dose_dqe,2)
                 app.DQE_array(i)=DQE(app,app.dose_dqe(i), app.DE_2,app.NED);
            end
            cla(app.DQE_dose_plot);
            xlabel(app.DQE_dose_plot,"dose, "+app.unitsDoseDQEDropDown.Value);
            xlim(app.DQE_dose_plot,[0 app.maxDoseEditField.Value]);
            plot(app.DQE_dose_plot, app.dose_dqe/doseCoef, app.DQE_array,'Color',[0.48 0.82 0.76],'LineWidth',1.5);
            %Energy dependencies
            switch (app.unitsLinAttCoefDropDown.Value)
                    case 'cm^-1'
                        app.u = 10^2*app.u_loaded;
                    case 'm^-1'
                        app.u = app.u_loaded;
            end
                switch(app.unitsEnergyDropDown.Value)
                    case 'keV'
                        app.E_u = app.E_u_loaded;
                    case 'eV'
                        app.E_u = 10^-3*app.E_u_loaded;
                    case 'MeV'
                        app.E_u = 10^3*app.E_u_loaded;
                end
            if app.u ~= 0 & app.E_u ~= 0
                
                app.E = app.E_u(1):app.E_u(1)+app.E_u(length(app.E_u))/2000:app.E_u(length(app.E_u));
                app.AE = 1 - exp(-app.d*u_interpolate(app,app.E,app.E_u, app.u)); %Attenuation efficiency
                HAE = u_interpolate(app,log(2)/app.d,app.u,app.E_u);
                cla(app.AE_plot);
                xlim(app.AE_plot, [0 app.E(length(app.E))]);
                plot(app.AE_plot, app.E, app.AE,'Color',[0.48 0.82 0.76],'LineWidth',1.5);
                text(app.AE_plot,app.E(round(length(app.E)*0.05)),0.2,"E_{1/2} = "+ num2str(HAE)+ " keV",'FontSize',14);
              
                app.E_mes = app.EmesEditField.Value;
                %app.u_mes = u_interpolate(app,app.E_mes,app.E_u, app.u);%m^-1
                app.u_mes = 10^2*app.umesEditField.Value;
                app.AE_mes = 1 - exp(-app.d*app.u_mes);

                app.DE_E = app.DE_2.*app.AE/app.AE_mes; %energy-dependent DE
                cla(app.DE_E_plot);
                xlim(app.DE_E_plot, [0 app.E(length(app.E))]);
                plot(app.DE_E_plot,app.E,app.DE_E,'Color',[0.48 0.82 0.76],'LineWidth',1.5);
      
                app.NED_E = zeros(size(app.E));
                for i=1:size(app.NED_E,2)
                    app.NED_E(i)=app.NED*app.E_mes/app.AE_mes*app.AE(i)/app.E(i); %energy-dependent NED
                end
                cla(app.NED_E_plot);
                xlim(app.NED_E_plot, [0 app.E(length(app.E))]);
                plot(app.NED_E_plot,app.E,app.NED_E,'Color',[0.48 0.82 0.76],'LineWidth',1.5);

                hold(app.DQE_E_plot,'on');
              
                cla(app.DQE_E_plot);
                
                legend(app.DQE_E_plot);
                xlim(app.DQE_E_plot, [0 app.E(length(app.E))]);
                plot(app.DQE_E_plot,app.E, DQE_E(app,app.DE_E,app.NED_E,photonToDose(app,app.DQEcurve1EditField.Value,app.u_p_E_av,app.detectorArea)),"blue",'DisplayName',num2str(app.DQEcurve1EditField.Value,'%.2e')+" photons" );
                plot(app.DQE_E_plot,app.E, DQE_E(app,app.DE_E,app.NED_E,photonToDose(app,app.DQEcurve2EditField.Value,app.u_p_E_av,app.detectorArea)),"green",'DisplayName',num2str(app.DQEcurve2EditField.Value,'%.2e')+" photons" );
                plot(app.DQE_E_plot,app.E, DQE_E(app,app.DE_E,app.NED_E,photonToDose(app,app.DQEcurve3EditField.Value,app.u_p_E_av,app.detectorArea)),"red",'DisplayName',num2str(app.DQEcurve3EditField.Value,'%.2e')+" photons" );
                hold(app.DQE_E_plot,'off');
            end
        
            
            %% Arrray detector figures %%
            cla(app.ESF_plot);
            cla(app.LSF_plot);
            cla(app.MTF_plot);
            cla(app.Noise2D_plot);
            cla(app.NPS_plot);
            cla(app.NNPS_plot);
            cla(app.DQE_f_plot);
            app.distance = app.pitchEditField.Value*10^-3*app.coordinates;%mm now
            if length(app.ESF) > 3 & length(app.distance) > 3 & size(app.NoiseMatrix,1) > 2 & size(app.NoiseMatrix,2) > 2  %ESF & Noise2D
                xlim(app.ESF_plot,[0 app.distance(length(app.distance))]);
                scatter(app.ESF_plot,app.distance,app.ESF, 25, 'MarkerFaceColor',[0.48 0.82 0.76]);
                new_distance = zeros(size(app.distance,1)-1,1);
                for i=1:(size(new_distance,1))
                   new_distance(i) = app.distance(i);
                end
                %LSF
                app.LSF = diff(app.ESF); %linear spreaf function
                [xData, yData] = prepareCurveData( new_distance, app.LSF );
                ft = fittype( 'a/sqrt(2*pi)/ro*exp(-(x-u)^2/2/ro^2)', 'independent', 'x', 'dependent', 'y' );
                opts = fitoptions( 'Method', 'NonlinearLeastSquares' );
                opts.Algorithm = 'Levenberg-Marquardt';
                opts.Display = 'Off';
                opts.MaxIter = 1000;
                opts.StartPoint = [0.489764395788231 0.445586200710899 0.709364830858073];
                opts.TolFun = 1e-12;
                opts.TolX = 1e-12;
                % Fit model to data.
                [fitresult, gof] = fit( xData, yData, ft, opts );
                gauss = fitresult.a/sqrt(2*pi)/fitresult.ro*exp(-(new_distance-fitresult.u).^2/2/fitresult.ro^2);
                scatter(app.LSF_plot,new_distance,app.LSF, 25, 'MarkerFaceColor',[0.48 0.82 0.76]); %LSF
                hold(app.LSF_plot, 'on');
                plot(app.LSF_plot,new_distance,gauss,"red") % fit function
                hold(app.LSF_plot, 'off');
                %MTF
                FFT_gauss = fft(gauss);
                OTF = zeros(1, round(length(FFT_gauss)/2));
                for i=1:length(OTF)
                    OTF(i) = FFT_gauss(i);
                end
                MTF = abs(OTF)/abs(OTF(1)); %MTF
                dx = new_distance(size(new_distance,1))/(size(new_distance,1)-1);% step in distance
                fs = 1/dx; %mm^-1
                f = fs*(0:length(new_distance)/2)/length(new_distance);
                spatialRes = u_interpolate(app,0.2,MTF,f);
                plot(app.MTF_plot,f,MTF,'Color',[0.48 0.82 0.76],'LineWidth',1.5);
                xlim(app.MTF_plot,[0,app.MTFlimitEditField.Value]);
                text(app.MTF_plot,round(app.MTFlimitEditField.Value*0.025),0.1,"f_{sp.res} = "+ num2str(spatialRes)+ " lp/mm",'FontSize',14);
               %Noise matrix
                hold(app.Noise2D_plot,'on');
                image(app.NoiseMatrix,'Parent',app.Noise2D_plot,'CDataMapping','scaled'); %noise from detector
               xlim(app.Noise2D_plot,[0 size(app.NoiseMatrix,1)]);
                ylim(app.Noise2D_plot,[0 size(app.NoiseMatrix,2)]);
               box(app.Noise2D_plot,'on');
                axis(app.Noise2D_plot,'ij');
                hold(app.Noise2D_plot,'off');
                %Set the remaining axes properties
                set(app.Noise2D_plot,'Layer','top');
                colorbar(app.Noise2D_plot);
                %NPS
               Noise_averaged = mean(app.NoiseMatrix,"all");
           
               % DFT of NoiseMatrix
                [M,N]=size(app.NoiseMatrix);
                F_noise=zeros(size(app.NoiseMatrix));
                F = zeros(size(app.NoiseMatrix));
                for uu=1:M
                    for v=1:N
                        for m=1:M
                            for n=1:N
                                F(uu,v)=1/size(app.NoiseMatrix,1)/size(app.NoiseMatrix,2)*(app.NoiseMatrix(m,n) - Noise_averaged).^2*exp(-1i*2*pi*((uu-1)*(m-1)/M+(v-1)*(n-1)/N)/size(app.NoiseMatrix,1));
                                F_noise(uu,v)=F_noise(uu,v)+F(uu,v);
                            end
                        end
                    end
                end 
                NPS = abs(F_noise(1,:))*app.detectorArea*10^6;
                NNPS = NPS/NPS(1,1); %normalized NPS
                f2 = fs*(0:length(NPS)-1)/length(NPS);
                plot(app.NPS_plot, f2,NPS,'Color',[0.48 0.82 0.76],'LineWidth',1.5);
                xlim(app.NPS_plot,[0,app.MTFlimitEditField.Value]);
                plot(app.NNPS_plot, f2,NNPS,'Color',[0.48 0.82 0.76],'LineWidth',1.5);
                xlim(app.NNPS_plot,[0,app.MTFlimitEditField.Value]);
                %MTF(f)
                if length(MTF)<length(NNPS)
                    DQE_f = zeros(1,length(MTF));
                    for i=1:length(MTF)
                        DQE_f(i) = MTF(i)^2/NNPS(i);
                    end
                else 
                    DQE_f = zeros(1,length(NNPS));
                    for i=1:length(NNPS)
                        DQE_f(i) = MTF(i)^2/NNPS(i);
                    end
                end
                f2_new = zeros(1,length(DQE_f));
                for i=1:length(DQE_f)
                    f2_new(i) = f2(i);
                end
                plot(app.DQE_f_plot,f2_new,DQE_f,'Color',[0.48 0.82 0.76],'LineWidth',1.5)
                xlim(app.DQE_f_plot,[0,app.MTFlimitEditField.Value]);
           end


        end

        % Button pushed function: NoiseButton
        function NoiseButtonPushed(app, event)
            %[file,path] = uigetfile({'*.txt';'*.dat';'*.*'},app.commonPath);
            [file,path] = uigetfile(app.commonPath +"*.txt;*.dat");
            fileID = fopen(fullfile(path,file), 'r');
            app.Noise_loaded = fscanf(fileID,"%f");
            fclose(fileID);
            app.fileAddressNoise.Value = file;
            app.commonPath = path;
        end

        % Button pushed function: DosenoiseButton
        function DosenoiseButtonPushed(app, event)
            %[file,path] = uigetfile({'*.txt';'*.dat';'*.*'},app.commonPath);
            [file,path] = uigetfile(app.commonPath +"*.txt;*.dat");
            fileID = fopen(fullfile(path,file), 'r');
            app.DoseNoise_loaded = fscanf(fileID,"%f"); %in ?Gy now
            fclose(fileID);
            app.fileAddressDoseN.Value = file;
            app.commonPath = path;
        end

        % Button pushed function: LinattencoefButton
        function LinattencoefButtonPushed(app, event)
            %[file,path] = uigetfile({'*.txt';'*.dat';'*.*'},app.commonPath);
            [file,path] = uigetfile(app.commonPath +"*.txt;*.dat");
            fileID = fopen(fullfile(path,file), 'r');
            app.u_loaded = fscanf(fileID,"%f"); %in ?m^-1 now
            fclose(fileID);
            app.fileAddressLinCoef.Value = file;
            app.commonPath = path;
        end

        % Button pushed function: EnergyButton
        function EnergyButtonPushed(app, event)
            %[file,path] = uigetfile({'*.txt';'*.dat';'*.*'},app.commonPath);
            [file,path] = uigetfile(app.commonPath +"*.txt;*.dat");
            fileID = fopen(fullfile(path,file), 'r');
            app.E_u_loaded = fscanf(fileID,"%f"); %?eV
            fclose(fileID);
            app.fileAddressEnergy.Value = file;
            app.commonPath = path;
        end

        % Button pushed function: ESFButton
        function ESFButtonPushed(app, event)
            %[file,path] = uigetfile({'*.txt';'*.dat';'*.*'},app.commonPath);
            [file,path] = uigetfile(app.commonPath +"*.txt;*.dat");
            fileID = fopen(fullfile(path,file), 'r');
            app.ESF = fscanf(fileID,"%f"); %no unit
            fclose(fileID);
            app.fileAddressESF.Value = file;
            app.commonPath = path;
        end

        % Button pushed function: PixelsnumbersButton
        function PixelsnumbersButtonPushed(app, event)
            %[file,path] = uigetfile({'*.txt';'*.dat';'*.*'},app.commonPath);
            [file,path] = uigetfile(app.commonPath +"*.txt;*.dat");
            fileID = fopen(fullfile(path,file), 'r');
            app.coordinates = fscanf(fileID,"%f"); 
            fclose(fileID);
            app.fileAddressPixelCoord.Value = file;
            app.commonPath = path;
        end

        % Button pushed function: DetectorNoise2DButton
        function DetectorNoise2DButtonPushed(app, event)
            %[file,path] = uigetfile({'*.txt';'*.dat';'*.*'},app.commonPath);
            [file,path] = uigetfile(app.commonPath +"*.txt;*.dat");
            fileID = fopen(fullfile(path,file), 'r');
            %app.NoiseMatrix = fscanf(fileID,"%f"); %mm now
            app.NoiseMatrix = readmatrix(fullfile(path,file));
            fclose(fileID);
            app.fileAddressDetNoise2D.Value = file;
            app.commonPath = path;
        end

        % Button pushed function: loadExampleButton
        function loadExampleButtonPushed(app, event)
            %Signal
            fileID = fopen('Example/Signal_GOS.txt', 'r');
            app.Signal_loaded = fscanf(fileID,"%f");
            fclose(fileID);
            app.fileAddressSignal.Value = 'Example/Signal_GOS.txt';
            app.unitsSignalDropDown.Value = 'C';
            %DoseSignal
            fileID = fopen('Example/Dose_signal_GOS.txt', 'r');
            app.DoseSignal_loaded = fscanf(fileID,"%f");
            fclose(fileID);
            app.fileAddressDoseS.Value = 'Example/Dose_signal_GOS.txt';
            app.unitsDoseSignalDropDown.Value = 'mGy';
            %Noise
            fileID = fopen('Example/Noise_GOS.txt', 'r');
            app.Noise_loaded = fscanf(fileID,"%f");
            fclose(fileID);
            app.fileAddressNoise.Value = 'Example/Noise_GOS.txt';
            app.unitsNoiseDropDown.Value = 'C';
            %DoseNoise
            fileID = fopen('Example/Dose_noise_GOS.txt', 'r');
            app.DoseNoise_loaded = fscanf(fileID,"%f");
            fclose(fileID);
            app.fileAddressDoseN.Value = 'Example/Dose_noise_GOS.txt';
            app.unitsDoseNoiseDropDown.Value = 'mGy';
            %LinAttCoef
            fileID = fopen('Example/u_GOS.txt', 'r');
            app.u_loaded = fscanf(fileID,"%f");
            fclose(fileID);
            app.fileAddressLinCoef.Value = 'Example/u_GOS.txt';
            app.unitsLinAttCoefDropDown.Value = 'cm^-1';
            %Energy
            fileID = fopen('Example/u_energy_GOS.txt', 'r');
            app.E_u_loaded = fscanf(fileID,"%f");
            fclose(fileID);
            app.fileAddressEnergy.Value = 'Example/u_energy_GOS.txt';
            app.unitsEnergyDropDown.Value = 'keV';
            %ESF
            fileID = fopen('Example/Signal_profile_GOS_pos_III.txt', 'r');
            app.ESF = fscanf(fileID,"%f");
            fclose(fileID);
            app.fileAddressESF.Value = 'Example/Signal_profile_GOS_pos_III.txt';
            %pixels
            fileID = fopen('Example/Distance_GOS_pixels_pos_III_pixels.txt', 'r');
            app.coordinates = fscanf(fileID,"%f");
            fclose(fileID);
            app.fileAddressPixelCoord.Value = 'Example/Distance_GOS_pixels_pos_III_pixels.txt';
            app.pitchEditField.Value = 19; %um
            %NoiseMatrix
            %fileID = fopen('Example/Noise_2D_GOS.txt', 'r');
            %app.NoiseMatrix = fscanf(fileID,"%f");
            %fclose(fileID);
            app.NoiseMatrix = readmatrix('Example/Noise_2D_GOS.txt');
            app.fileAddressDetNoise2D.Value = 'Example/Noise_2D_GOS.txt';
            %constants
            app.DetectorAreaEditField.Value = 0.000361; %mm^2
            app.thicknessEditField.Value = 0.5; %mm
            app.massAttCoefEditField.Value = 0.06;  
            app.massAttCoefByEnergyEditField.Value = 4;
            app.EmesEditField.Value = 40; %keV
            app.umesEditField.Value = 50;%cm^-1
            app.E_bandGapEditField.Value = 1.5; %eV
            app.E_pairEditField.Value = 2.8; % x band gap
            %Single pixel 
            app.minDoseEditField.Value = 0;%
            app.maxDoseEditField.Value = 32;%
            app.stepDoseEditField.Value = 0.01;%mGy
            %app.fitInterceptCorrectionEditField.Value = 2.42; %C^2
            app.DQEcurve1EditField.Value = 10^4; %photons
            app.DQEcurve2EditField.Value = 10^5; %photons
            app.DQEcurve3EditField.Value = 10^6; %photons
            
            %array detector panel
            app.MTFlimitEditField.Value = 10; %lp/mm
        end

        % Button down function: SignalToDose
        function SignalToDoseButtonDown(app, event)
            [file,path] = uiputfile({'*.png'; '*.jpeg';'*.tiff'; '*.pdf'},'Save plot as','Signal(dose)');
            if file(length(file)-3:length(file)) == "pdf"
                exportgraphics(app.SignalToDose,fullfile(path,file),'ContentType','vector');
            else
            exportgraphics(app.SignalToDose,fullfile(path,file));
            end
        end

        % Button down function: NoiseToDose
        function NoiseToDoseButtonDown(app, event)
            [file,path] = uiputfile({'*.png'; '*.jpeg';'*.tiff'; '*.pdf'},'Save plot as','Noise(dose)');
            if file(length(file)-3:length(file)) == "pdf"
                exportgraphics(app.NoiseToDose,fullfile(path,file),'ContentType','vector');
            else
            exportgraphics(app.NoiseToDose,fullfile(path,file));
            end
        end

        % Button down function: DQE_dose_plot
        function DQE_dose_plotButtonDown(app, event)
            [file,path] = uiputfile({'*.png'; '*.jpeg';'*.tiff'; '*.pdf'},'Save plot as','DQE(dose)');
            if file(length(file)-3:length(file)) == "pdf"
                exportgraphics(app.DQE_dose_plot,fullfile(path,file),'ContentType','vector');
            else
            exportgraphics(app.DQE_dose_plot,fullfile(path,file));
            end
        end

        % Button down function: AE_plot
        function AE_plotButtonDown(app, event)
            [file,path] = uiputfile({'*.png'; '*.jpeg';'*.tiff'; '*.pdf'},'Save plot as','AE');
            if file(length(file)-3:length(file)) == "pdf"
                exportgraphics(app.AE_plot,fullfile(path,file),'ContentType','vector');
            else
            exportgraphics(app.AE_plot,fullfile(path,file));
            end
        end

        % Button down function: DQE_E_plot
        function DQE_E_plotButtonDown(app, event)
            [file,path] = uiputfile({'*.png'; '*.jpeg';'*.tiff'; '*.pdf'},'Save plot as','DQE(E)');
            if file(length(file)-3:length(file)) == "pdf"
                exportgraphics(app.DQE_E_plot,fullfile(path,file),'ContentType','vector');
            else
            exportgraphics(app.DQE_E_plot,fullfile(path,file));
            end
        end

        % Button down function: DE_E_plot
        function DE_E_plotButtonDown(app, event)
            [file,path] = uiputfile({'*.png'; '*.jpeg';'*.tiff'; '*.pdf'},'Save plot as','DE(E)');
            if file(length(file)-3:length(file)) == "pdf"
                exportgraphics(app.DE_E_plot,fullfile(path,file),'ContentType','vector');
            else
            exportgraphics(app.DE_E_plot,fullfile(path,file));
            end
        end

        % Button down function: NED_E_plot
        function NED_E_plotButtonDown(app, event)
            [file,path] = uiputfile({'*.png'; '*.jpeg';'*.tiff'; '*.pdf'},'Save plot as','NED(E)');
            if file(length(file)-3:length(file)) == "pdf"
                exportgraphics(app.NED_E_plot,fullfile(path,file),'ContentType','vector');
            else
            exportgraphics(app.NED_E_plot,fullfile(path,file));
            end
        end

        % Button down function: ESF_plot
        function ESF_plotButtonDown(app, event)
            [file,path] = uiputfile({'*.png'; '*.jpeg';'*.tiff'; '*.pdf'},'Save plot as','ESF');
            if file(length(file)-3:length(file)) == "pdf"
                exportgraphics(app.ESF_plot,fullfile(path,file),'ContentType','vector');
            else
            exportgraphics(app.ESF_plot,fullfile(path,file));
            end
        end

        % Button down function: LSF_plot
        function LSF_plotButtonDown(app, event)
            [file,path] = uiputfile({'*.png'; '*.jpeg';'*.tiff'; '*.pdf'},'Save plot as','LSF');
            if file(length(file)-3:length(file)) == "pdf"
                exportgraphics(app.LSF_plot,fullfile(path,file),'ContentType','vector');
            else
            exportgraphics(app.LSF_plot,fullfile(path,file));
            end
        end

        % Button down function: MTF_plot
        function MTF_plotButtonDown(app, event)
            [file,path] = uiputfile({'*.png'; '*.jpeg';'*.tiff'; '*.pdf'},'Save plot as','MTF');
            if file(length(file)-3:length(file)) == "pdf"
                exportgraphics(app.MTF_plot,fullfile(path,file),'ContentType','vector');
            else
            exportgraphics(app.MTF_plot,fullfile(path,file));
            end
        end

        % Button down function: Noise2D_plot
        function Noise2D_plotButtonDown(app, event)
            [file,path] = uiputfile({'*.png'; '*.jpeg';'*.tiff'; '*.pdf'},'Save plot as','Noise2D');
            if file(length(file)-3:length(file)) == "pdf"
                exportgraphics(app.Noise2D_plot,fullfile(path,file),'ContentType','vector');
            else
            exportgraphics(app.Noise2D_plot,fullfile(path,file));
            end
        end

        % Button down function: NPS_plot
        function NPS_plotButtonDown(app, event)
            [file,path] = uiputfile({'*.png'; '*.jpeg';'*.tiff'; '*.pdf'},'Save plot as','NPS');
            if file(length(file)-3:length(file)) == "pdf"
                exportgraphics(app.NPS_plot,fullfile(path,file),'ContentType','vector');
            else
            exportgraphics(app.NPS_plot,fullfile(path,file));
            end
        end

        % Button down function: NNPS_plot
        function NNPS_plotButtonDown(app, event)
            [file,path] = uiputfile({'*.png'; '*.jpeg';'*.tiff'; '*.pdf'},'Save plot as','NNPS');
            if file(length(file)-3:length(file)) == "pdf"
                exportgraphics(app.NNPS_plot,fullfile(path,file),'ContentType','vector');
            else
            exportgraphics(app.NNPS_plot,fullfile(path,file));
            end
        end

        % Button down function: DQE_f_plot
        function DQE_f_plotButtonDown(app, event)
            [file,path] = uiputfile({'*.png'; '*.jpeg';'*.tiff'; '*.pdf'},'Save plot as','DQE(f)');
            if file(length(file)-3:length(file)) == "pdf"
                exportgraphics(app.DQE_f_plot,fullfile(path,file),'ContentType','vector');
            else
            exportgraphics(app.DQE_f_plot,fullfile(path,file));
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {868, 868};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {273, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.LeftPanel);
            app.SignalButton = uibutton(app.GridLayout2, 'push');
            app.SignalButton.ButtonPushedFcn = createCallbackFcn(app, @SignalButtonPushed, true);
            app.SignalButton.Text = 'Signal';
            app.DosesignalButton = uibutton(app.GridLayout2, 'push');
            app.DosesignalButton.ButtonPushedFcn = createCallbackFcn(app, @DosesignalButtonPushed, true);
            app.DosesignalButton.Text = 'Dose(signal)';
            app.NoiseButton = uibutton(app.GridLayout2, 'push');
            app.NoiseButton.ButtonPushedFcn = createCallbackFcn(app, @NoiseButtonPushed, true);
            app.NoiseButton.Text = 'Noise';
            app.DosenoiseButton = uibutton(app.GridLayout2, 'push');
            app.DosenoiseButton.ButtonPushedFcn = createCallbackFcn(app, @DosenoiseButtonPushed, true);
            app.DosenoiseButton.Text = 'Dose(noise)';
            app.FileinputLabel = uilabel(app.GridLayout2);
            app.FileinputLabel.Text = 'File input';
            app.LinattencoefButton = uibutton(app.GridLayout2, 'push');
            app.LinattencoefButton.ButtonPushedFcn = createCallbackFcn(app, @LinattencoefButtonPushed, true);
            app.LinattencoefButton.Text = 'Lin. atten. coef.';
            app.EnergyButton = uibutton(app.GridLayout2, 'push');
            app.EnergyButton.ButtonPushedFcn = createCallbackFcn(app, @EnergyButtonPushed, true);
            app.EnergyButton.Text = 'Energy';
            app.ESFButton = uibutton(app.GridLayout2, 'push');
            app.ESFButton.ButtonPushedFcn = createCallbackFcn(app, @ESFButtonPushed, true);
            app.ESFButton.Text = 'ESF';
            app.PixelsnumbersButton = uibutton(app.GridLayout2, 'push');
            app.PixelsnumbersButton.ButtonPushedFcn = createCallbackFcn(app, @PixelsnumbersButtonPushed, true);
            app.PixelsnumbersButton.Text = 'Pixels (numbers)';
            app.DetectorNoise2DButton = uibutton(app.GridLayout2, 'push');
            app.DetectorNoise2DButton.ButtonPushedFcn = createCallbackFcn(app, @DetectorNoise2DButtonPushed, true);
            app.DetectorNoise2DButton.Text = 'Detector Noise (2D)';
            app.fileAddressSignal = uitextarea(app.GridLayout2);
            app.fileAddressDoseS = uitextarea(app.GridLayout2);
            app.fileAddressNoise = uitextarea(app.GridLayout2);
            app.fileAddressDoseN = uitextarea(app.GridLayout2);
            app.fileAddressLinCoef = uitextarea(app.GridLayout2);
            app.fileAddressEnergy = uitextarea(app.GridLayout2);
            app.fileAddressESF = uitextarea(app.GridLayout2);
            app.fileAddressPixelCoord = uitextarea(app.GridLayout2);
            app.fileAddressDetNoise2D = uitextarea(app.GridLayout2);
            app.CalculateButton = uibutton(app.GridLayout2, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.unitsSignalDropDown = uidropdown(app.GridLayout2);
            app.unitsSignalDropDown.Items = {'C', 'mC', 'uC'};
            app.unitsSignalDropDown.Value = 'C';
            app.unitsNoiseDropDown = uidropdown(app.GridLayout2);
            app.unitsNoiseDropDown.Items = {'C', 'mC', 'uC'};
            app.unitsNoiseDropDown.Value = 'C';
            app.unitsDoseSignalDropDown = uidropdown(app.GridLayout2);
            app.unitsDoseSignalDropDown.Items = {'Gy', 'mGy', 'uGy'};
            app.unitsDoseSignalDropDown.Value = 'mGy';
            app.unitsDoseNoiseDropDown = uidropdown(app.GridLayout2);
            app.unitsDoseNoiseDropDown.Items = {'Gy', 'mGy', 'uGy'};
            app.unitsDoseNoiseDropDown.Value = 'mGy';
            app.unitsLinAttCoefDropDown = uidropdown(app.GridLayout2);
            app.unitsLinAttCoefDropDown.Items = {'cm^-1', 'm^-1'};
            app.unitsLinAttCoefDropDown.Value = 'cm^-1';
            app.unitsEnergyDropDown = uidropdown(app.GridLayout2);
            app.unitsEnergyDropDown.Items = {'eV', 'keV', 'MeV'};
            app.unitsEnergyDropDown.Value = 'keV';
            app.loadExampleButton = uibutton(app.GridLayout2, 'push');
            app.loadExampleButton.ButtonPushedFcn = createCallbackFcn(app, @loadExampleButtonPushed, true);
            app.loadExampleButton.Text = 'Load example';
            app.Detectorareamm2Label_2 = uilabel(app.GridLayout2);
            app.Detectorareamm2Label_2.Text = 'Detector area (mm^2)';
            app.DetectorAreaEditField = uieditfield(app.GridLayout2, 'numeric');
            app.DetectorAreaEditField.Value = 0.000361;
            app.MaterialThicknessLabel_8 = uilabel(app.GridLayout2);
            app.MaterialThicknessLabel_8.Text = 'Material thickness (mm)';
            app.thicknessEditField = uieditfield(app.GridLayout2, 'numeric');
            app.thicknessEditField.Value = 0.000361;
            app.MaterialThicknessLabel_9 = uilabel(app.GridLayout2);
            app.MaterialThicknessLabel_9.Text = {'<μ/ρ>, cm^2 g^-1'; '(averaged )'};
            app.massAttCoefEditField = uieditfield(app.GridLayout2, 'numeric');
            app.massAttCoefEditField.Value = 0.06;
            app.MaterialThicknessLabel_10 = uilabel(app.GridLayout2);
            app.MaterialThicknessLabel_10.Text = {'X-ray energy'; 'during measurement (keV)'};
            app.EmesEditField = uieditfield(app.GridLayout2, 'numeric');
            app.EmesEditField.Value = 40;
            app.MaterialThicknessLabel_11 = uilabel(app.GridLayout2);
            app.MaterialThicknessLabel_11.Text = 'Energy band-gap, eV';
            app.E_bandGapEditField = uieditfield(app.GridLayout2, 'numeric');
            app.E_bandGapEditField.Value = 1.5;
            app.MaterialThicknessLabel_12 = uilabel(app.GridLayout2);
            app.MaterialThicknessLabel_12.Text = {'e-h pair creation'; 'energy'};
            app.E_pairEditField = uieditfield(app.GridLayout2, 'numeric');
            app.E_pairEditField.Value = 2.8;
            app.MaterialThicknessLabel_13 = uilabel(app.GridLayout2);
            app.MaterialThicknessLabel_13.Text = '<E*μ/ρ>, kev cm^2 g^-1';
            app.massAttCoefByEnergyEditField = uieditfield(app.GridLayout2, 'numeric');
            app.massAttCoefByEnergyEditField.Value = 4;
            app.MaterialThicknessLabel_14 = uilabel(app.GridLayout2);
            app.MaterialThicknessLabel_14.Text = 'μ at used energy (cm^-1)';
            app.umesEditField = uieditfield(app.GridLayout2, 'numeric');
            app.umesEditField.Value = 50;
            app.pitchumEditFieldLabel_2 = uilabel(app.GridLayout2);
            app.pitchumEditFieldLabel_2.Text = 'pitch, um';
            app.pitchEditField = uieditfield(app.GridLayout2, 'numeric');
            app.pitchEditField.Value = 19;
            app.xbandgapLabel = uilabel(app.GridLayout2);
            app.xbandgapLabel.Text = '  x band-gap';
            app.RightPanel = uipanel(app.GridLayout);
            app.VitaliiBartoshKovalenkoGroupETHZurichLabel = uilabel(app.RightPanel);
            app.VitaliiBartoshKovalenkoGroupETHZurichLabel.Text = '2023 Vitalii Bartosh, Kovalenko Group, ETH Zurich';
            app.TabGroup = uitabgroup(app.RightPanel);
            app.SinglepixelcharacterizationTab = uitab(app.TabGroup);
            app.SinglepixelcharacterizationTab.Title = 'Single pixel characterization';
            app.SignalToDose = uiaxes(app.SinglepixelcharacterizationTab);
            title(app.SignalToDose, 'Signal/dose dependence')
            xlabel(app.SignalToDose, 'dose, Gy')
            ylabel(app.SignalToDose, 'Signal, C')
            zlabel(app.SignalToDose, 'Z')
            app.SignalToDose.ButtonDownFcn = createCallbackFcn(app, @SignalToDoseButtonDown, true);
            app.NoiseToDose = uiaxes(app.SinglepixelcharacterizationTab);
            title(app.NoiseToDose, 'Sq. Noise/dose dependence')
            xlabel(app.NoiseToDose, 'dose, Gy')
            ylabel(app.NoiseToDose, 'Noise^2, C^2')
            zlabel(app.NoiseToDose, 'Z')
            app.NoiseToDose.ButtonDownFcn = createCallbackFcn(app, @NoiseToDoseButtonDown, true);
            app.DQE_dose_plot = uiaxes(app.SinglepixelcharacterizationTab);
            title(app.DQE_dose_plot, 'DQE/dose dependence')
            xlabel(app.DQE_dose_plot, 'dose, Gy')
            ylabel(app.DQE_dose_plot, 'DQE')
            zlabel(app.DQE_dose_plot, 'Z')
            app.DQE_dose_plot.ButtonDownFcn = createCallbackFcn(app, @DQE_dose_plotButtonDown, true);
            app.AE_plot = uiaxes(app.SinglepixelcharacterizationTab);
            title(app.AE_plot, 'Attenuation efficiency')
            xlabel(app.AE_plot, 'Energy, keV')
            ylabel(app.AE_plot, 'AE')
            zlabel(app.AE_plot, 'Z')
            app.AE_plot.ButtonDownFcn = createCallbackFcn(app, @AE_plotButtonDown, true);
            app.DE_E_plot = uiaxes(app.SinglepixelcharacterizationTab);
            title(app.DE_E_plot, 'DE/energy dependence')
            xlabel(app.DE_E_plot, 'Energy, keV')
            ylabel(app.DE_E_plot, 'DE_E')
            zlabel(app.DE_E_plot, 'Z')
            app.DE_E_plot.ButtonDownFcn = createCallbackFcn(app, @DE_E_plotButtonDown, true);
            app.NED_E_plot = uiaxes(app.SinglepixelcharacterizationTab);
            title(app.NED_E_plot, 'NED/energy dependence')
            xlabel(app.NED_E_plot, 'Energy, keV')
            ylabel(app.NED_E_plot, 'NED_E')
            zlabel(app.NED_E_plot, 'Z')
            app.NED_E_plot.YScale = 'log';
            app.NED_E_plot.ButtonDownFcn = createCallbackFcn(app, @NED_E_plotButtonDown, true);
            app.DQE_E_plot = uiaxes(app.SinglepixelcharacterizationTab);
            title(app.DQE_E_plot, 'DQE/energy dependence')
            xlabel(app.DQE_E_plot, 'Energy, keV')
            ylabel(app.DQE_E_plot, 'DQE_E')
            zlabel(app.DQE_E_plot, 'Z')
            app.DQE_E_plot.ButtonDownFcn = createCallbackFcn(app, @DQE_E_plotButtonDown, true);
            app.TheoreticalsensitivityTextAreaLabel_2 = uilabel(app.SinglepixelcharacterizationTab);
            app.TheoreticalsensitivityTextAreaLabel_2.Text = {'Theoretical '; 'sensitivity'};
            app.TheoreticalSensitivityTextArea = uitextarea(app.SinglepixelcharacterizationTab);
            app.DetectionefficiencyLabel_5 = uilabel(app.SinglepixelcharacterizationTab);
            app.DetectionefficiencyLabel_5.Text = {'Detection'; 'efficiency'};
            app.DETextArea = uitextarea(app.SinglepixelcharacterizationTab);
            app.DetectionefficiencyLabel_6 = uilabel(app.SinglepixelcharacterizationTab);
            app.DetectionefficiencyLabel_6.Text = {'Detection'; 'efficiency'; '(from noise analysis)'};
            app.DE_2TextArea = uitextarea(app.SinglepixelcharacterizationTab);
            app.DetectionefficiencyLabel_7 = uilabel(app.SinglepixelcharacterizationTab);
            app.DetectionefficiencyLabel_7.Text = {'NED'; '(in dose units)'};
            app.NEDdoseTextArea = uitextarea(app.SinglepixelcharacterizationTab);
            app.DetectionefficiencyLabel_8 = uilabel(app.SinglepixelcharacterizationTab);
            app.DetectionefficiencyLabel_8.Text = {'NED'; '(in photons)'};
            app.NEDphotonTextArea = uitextarea(app.SinglepixelcharacterizationTab);
            app.curve1EditFieldLabel_2 = uilabel(app.SinglepixelcharacterizationTab);
            app.curve1EditFieldLabel_2.Text = 'curve 1';
            app.DQEcurve1EditField = uieditfield(app.SinglepixelcharacterizationTab, 'numeric');
            app.DQEcurve1EditField.Value = 10000;
            app.curve2EditFieldLabel_2 = uilabel(app.SinglepixelcharacterizationTab);
            app.curve2EditFieldLabel_2.Text = 'curve 2';
            app.DQEcurve2EditField = uieditfield(app.SinglepixelcharacterizationTab, 'numeric');
            app.DQEcurve2EditField.Value = 100000;
            app.curve3EditFieldLabel_2 = uilabel(app.SinglepixelcharacterizationTab);
            app.curve3EditFieldLabel_2.Text = 'curve 3';
            app.DQEcurve3EditField = uieditfield(app.SinglepixelcharacterizationTab, 'numeric');
            app.DQEcurve3EditField.Value = 1000000;
            app.SpecifythedoseinphotonsLabel = uilabel(app.SinglepixelcharacterizationTab);
            app.SpecifythedoseinphotonsLabel.Text = 'Specify the dose in photons for DQE(E):';
            app.minEditFieldLabel_2 = uilabel(app.SinglepixelcharacterizationTab);
            app.minEditFieldLabel_2.Text = 'min';
            app.minDoseEditField = uieditfield(app.SinglepixelcharacterizationTab, 'numeric');
            app.maxEditFieldLabel_2 = uilabel(app.SinglepixelcharacterizationTab);
            app.maxEditFieldLabel_2.Text = 'max';
            app.maxDoseEditField = uieditfield(app.SinglepixelcharacterizationTab, 'numeric');
            app.maxDoseEditField.Value = 32;
            app.stepEditFieldLabel_2 = uilabel(app.SinglepixelcharacterizationTab);
            app.stepEditFieldLabel_2.Text = 'step';
            app.stepDoseEditField = uieditfield(app.SinglepixelcharacterizationTab, 'numeric');
            app.stepDoseEditField.Value = 0.01;
            app.SetDoseRangeLabel = uilabel(app.SinglepixelcharacterizationTab);
            app.SetDoseRangeLabel.Text = 'Set dose range for DQE';
            app.unitsDoseDQEDropDown = uidropdown(app.SinglepixelcharacterizationTab);
            app.unitsDoseDQEDropDown.Items = {'Gy', 'mGy', 'uGy'};
            app.unitsDoseDQEDropDown.Value = 'mGy';
            app.ArraydetectorcharacterizationTab = uitab(app.TabGroup);
            app.ArraydetectorcharacterizationTab.Title = 'Array detector characterization';
            app.ESF_plot = uiaxes(app.ArraydetectorcharacterizationTab);
            title(app.ESF_plot, 'Edge spread function')
            xlabel(app.ESF_plot, 'distance, mm')
            ylabel(app.ESF_plot, 'ESF')
            zlabel(app.ESF_plot, 'Z')
            app.ESF_plot.ButtonDownFcn = createCallbackFcn(app, @ESF_plotButtonDown, true);
            app.LSF_plot = uiaxes(app.ArraydetectorcharacterizationTab);
            title(app.LSF_plot, 'Linear spread function')
            xlabel(app.LSF_plot, 'distance, mm')
            ylabel(app.LSF_plot, 'LSF')
            zlabel(app.LSF_plot, 'Z')
            app.LSF_plot.ButtonDownFcn = createCallbackFcn(app, @LSF_plotButtonDown, true);
            app.MTF_plot = uiaxes(app.ArraydetectorcharacterizationTab);
            title(app.MTF_plot, 'Modulation transfer function')
            xlabel(app.MTF_plot, 'spatial frequency, lp/mm')
            ylabel(app.MTF_plot, 'MTF')
            zlabel(app.MTF_plot, 'Z')
            app.MTF_plot.ButtonDownFcn = createCallbackFcn(app, @MTF_plotButtonDown, true);
            app.Noise2D_plot = uiaxes(app.ArraydetectorcharacterizationTab);
            title(app.Noise2D_plot, 'Noise from the detector')
            xlabel(app.Noise2D_plot, 'pixel number')
            ylabel(app.Noise2D_plot, 'pixel number')
            zlabel(app.Noise2D_plot, 'Z')
            app.Noise2D_plot.ButtonDownFcn = createCallbackFcn(app, @Noise2D_plotButtonDown, true);
            app.NPS_plot = uiaxes(app.ArraydetectorcharacterizationTab);
            title(app.NPS_plot, 'Noise power spectrum')
            xlabel(app.NPS_plot, 'spatial frequency, lp/mm')
            ylabel(app.NPS_plot, 'NPS')
            zlabel(app.NPS_plot, 'Z')
            app.NPS_plot.ButtonDownFcn = createCallbackFcn(app, @NPS_plotButtonDown, true);
            app.DQE_f_plot = uiaxes(app.ArraydetectorcharacterizationTab);
            title(app.DQE_f_plot, 'Detective Quantum Efficiency')
            xlabel(app.DQE_f_plot, 'spatial frequency, lp/mm')
            ylabel(app.DQE_f_plot, 'DQE')
            zlabel(app.DQE_f_plot, 'Z')
            app.DQE_f_plot.ButtonDownFcn = createCallbackFcn(app, @DQE_f_plotButtonDown, true);
            app.NNPS_plot = uiaxes(app.ArraydetectorcharacterizationTab);
            title(app.NNPS_plot, 'Normalized noise power spectrum')
            xlabel(app.NNPS_plot, 'spatial frequency, lp/mm')
            ylabel(app.NNPS_plot, 'NNPS')
            zlabel(app.NNPS_plot, 'Z')
            app.NNPS_plot.ButtonDownFcn = createCallbackFcn(app, @NNPS_plotButtonDown, true);
            app.limitlpmmLabel_2 = uilabel(app.ArraydetectorcharacterizationTab);
            app.limitlpmmLabel_2.Text = 'limit, lp/mm';
            app.MTFlimitEditField = uieditfield(app.ArraydetectorcharacterizationTab, 'numeric');
            app.MTFlimitEditField.Value = 10;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = perovXimager

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end